classdef TDCgui5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                          matlab.ui.Figure
        PathEditField                matlab.ui.control.EditField
        PathLabel                    matlab.ui.control.Label
        tempLabel                    matlab.ui.control.Label
        tempGauge                    matlab.ui.control.NinetyDegreeGauge
        SystemTempLabel              matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        CalibrationTab               matlab.ui.container.Tab
        TDC1Lamp                     matlab.ui.control.Lamp
        TDC1LampLabel                matlab.ui.control.Label
        ChannelstatusLabel           matlab.ui.control.Label
        TDC0Lamp                     matlab.ui.control.Lamp
        TDC0LampLabel                matlab.ui.control.Label
        HistogramdataLabel           matlab.ui.control.Label
        ExportButton_2               matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        PlotcalibrationdataButton    matlab.ui.control.Button
        CountsLabel                  matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        EnableLamp                   matlab.ui.control.Lamp
        EnableButton                 matlab.ui.control.StateButton
        CalibrationInfoLabel         matlab.ui.control.Label
        TDCLabel                     matlab.ui.control.Label
        ChannelselectionButtonGroup  matlab.ui.container.ButtonGroup
        TDC1Button                   matlab.ui.control.RadioButton
        TDC0Button                   matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
        MeasurementTab               matlab.ui.container.Tab
        UnitsDropDown                matlab.ui.control.DropDown
        UnitsDropDownLabel           matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        EventsEditField              matlab.ui.control.NumericEditField
        EventsEditFieldLabel         matlab.ui.control.Label
        BinsEditField                matlab.ui.control.NumericEditField
        BinsEditFieldLabel           matlab.ui.control.Label
        AutoscaleCheckBox            matlab.ui.control.CheckBox
        GATELabel                    matlab.ui.control.Label
        sLabel                       matlab.ui.control.Label
        FirstSTOPCheckBox            matlab.ui.control.CheckBox
        psLabel_2                    matlab.ui.control.Label
        windowHighField              matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        psLabel                      matlab.ui.control.Label
        FromLabel                    matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
        ScaleSwitch                  matlab.ui.control.Switch
        windowLowField               matlab.ui.control.NumericEditField
        MeasurementwindowLabel       matlab.ui.control.Label
        StatsLabel                   matlab.ui.control.Label
        ClearButton2                 matlab.ui.control.Button
        StatisticsButton             matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        ZeroButton                   matlab.ui.control.Button
        OffsetLabel                  matlab.ui.control.Label
        CounterLabel                 matlab.ui.control.Label
        RUNButton                    matlab.ui.control.StateButton
        DropDownSTOP                 matlab.ui.control.DropDown
        STOPLabel                    matlab.ui.control.Label
        STARTLabel                   matlab.ui.control.Label
        DropDownSTART                matlab.ui.control.DropDown
        UIAxes2                      matlab.ui.control.UIAxes
        SpeedtestTab                 matlab.ui.container.Tab
        Benchmark2Label              matlab.ui.control.Label
        Benchmark2Button             matlab.ui.control.StateButton
        MatlabGUILabel               matlab.ui.control.Label
        Benchmark1Label              matlab.ui.control.Label
        Benchmark1Button             matlab.ui.control.StateButton
        ARMCprogramLabel             matlab.ui.control.Label
        ULabel                       matlab.ui.control.Label
        TimestampcollectionbenchmarkLabel  matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        Port                         matlab.ui.control.NumericEditField
        PortEditFieldLabel           matlab.ui.control.Label
        RPaddr                       matlab.ui.control.EditField
        RedPitayaIPAddressLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        
        connected = 0; % connection status
        client; % TCP client object
        
        Nch = 2; % No. of TDC channels
        Ntaps = 192; % No. of delay line taps
        T = 10^6 / 350; % clock period in ps (f = 350 MHz)
        BRAMsize = 2048;
        
        bitMask8 = hex2dec('ff'); % 255
        bitMask16 = hex2dec('ffff'); % 65535
        bitMask24 = hex2dec('ffffff'); % 16777215
        
        tcpBusy = 0; % encloses write-read pairs
        
        % ---------------CALIBRATION---------------------------------------
        
        TDCenabled; % TDC channel enabled array (1 x Nch)
        selectedTDC = 0; % displayed TDC channel
        
        counts; % number of counts in each channel (1 x Nch)
        histogramData; % histogram values for each channel (Nch x Ntaps)
        
        calibrationPts; % number of points used for calibration (1 x Nch)
        binWidths; % bin widths in ps for each channel
        calibrationTable; % bin No. to ps LUT
        activeTaps; % No. of active taps (1 x Nch)
        avgWidth; % average bin width in ps (1 x Nch)
        
        DNL; % differential nonlinearity (Nch x Ntaps)
        INL; % integral nonlinearity (Nch x Ntaps)
        
        % ----------------MEASUREMENT------------------------------------------
        
        startTDC = 0; % START channel index
        stopTDC = 0; % STOP channel index
        
        TDCmeasurements; % measured time differences in ps (Nch x Nch x 500*BRAMsize) ... million points deep
        Nmeasurements; % number of measured points matrix (Nch x Nch)
        initialCounts; % Nmeasurements at start of measurement
        STARTcounts; % all counts in START channel matrix (Nch x Nch)
        STOPcounts; % all counts in STOP channel matrix (Nch x Nch)
        offset; % channel offset matrix (Nch x Nch); zero diagonal elements
        
        windowHigh; % measurement window (Nch x Nch)
        windowLow;
        unitsMode = 1; % 4 unit options: ps (default), ns, us, ms
        unitsMultiplier = [1 1000 1000000 1000000000];
        unitsSymbol = ["ps", "ns", "us", "ms"]
        
        % measurement fields memory variables
        EventsEditField_var; % (Nch x Nch)
        TimeEditField_var; % (Nch x Nch)
        FirstSTOPCheckBox_var; % (Nch x Nch)
        
        Nbins; % histogram
        measurementHistogramCounts; % histogram counts of measured TDC values (Nbins)
        measurementHistogramEdges; % histogram edges of measured TDC values (Nbins+1)
        
        totalTime; % length of measurement in seconds (Nch x Nch)
        initialTime; % totalTime at start of measurement
        t1; % clock time at start of measurement
        
        % ----------------TEMPETARUTE MONITORING-------------------------------
        
        xadcTimer; % timer object for system monitoring
        timerFlag; % triggered timer flag
        
        % -----------------BENCHMARKING----------------------------------------
        
        benchmarkTimer1; % timer object for the 10 second speedtest (1)
        benchmarkTimer2; % timer object for the 10 second speedtest (2)
        
        % -----------------FILE------------------------------------------------
        fileDirectory = 'C:\Users\Michel\Desktop\MATLABexports';  % folder for export/import operations
        fileIndex; % counter for writing histograms to files
        
    end

    methods (Access = private)
    
        function activeFields(app) % manages which fields are enabled
            
            % Enable button, Clear button (CALIBRATION tab)
            if (app.connected == 0) || (app.RUNButton.Value == 1) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.EnableButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
            else
                app.EnableButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
            end
            
            % RUN button (MEASUREMENT tab)
            if (app.connected == 0) || (sum(app.TDCenabled) > 0) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.RUNButton.Enable = 'off';
            else
                app.RUNButton.Enable = 'on';
            end
            
            % Measurement offset stuff, 1stSTOP checkbox (MEASUREMENT tab)
            if app.startTDC == app.stopTDC
                app.OffsetLabel.Enable = 'off';
                app.ZeroButton.Enable = 'off';
                app.ResetButton.Enable = 'off';
                app.FirstSTOPCheckBox.Enable = 'off';
            else
                app.OffsetLabel.Enable = 'on';
                app.ZeroButton.Enable = 'on';
                app.ResetButton.Enable = 'on';
                app.FirstSTOPCheckBox.Enable = 'on';
            end
            
            % Dropdown channel selection & gate (MEASUREMENT tab)
            if app.RUNButton.Value == 1
                app.DropDownSTART.Enable = 'off';
                app.DropDownSTOP.Enable = 'off';
                app.EventsEditField.Editable = 'off';
                app.TimeEditField.Editable = 'off';
            else
                app.DropDownSTART.Enable = 'on';
                app.DropDownSTOP.Enable = 'on';
                app.EventsEditField.Editable = 'on';
                app.TimeEditField.Editable = 'on';
            end
            
            % Disconnect button
            if (app.connected == 1) && ( (app.RUNButton.Value == 1) || (sum(app.TDCenabled) > 0) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1) )
                app.ConnectButton.Enable = 'off';
            else
                app.ConnectButton.Enable = 'on';
            end
            
            % Temperature gauge
            if app.connected == 0
                app.tempGauge.Value = 0;
                app.tempLabel.Text = 'N/A';
                app.tempLabel.FontColor = 'k';
            end
            
            % Benchmark Run buttons (SPEEDTEST tab)
            if (app.connected == 0) || (sum(app.TDCenabled) > 0) || (app.RUNButton.Value == 1) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.Benchmark1Button.Enable = 'off';
                app.Benchmark2Button.Enable = 'off';
                
            else
                app.Benchmark1Button.Enable = 'on';
                app.Benchmark2Button.Enable = 'on';
            end
            
        end
        
        function MeasurementDynamics(app) % dynamically refreshing part of Measurement Panel
            
            % plot histogram of measurements (Nbins bins)
            if app.AutoscaleCheckBox.Value == 1 % autoscale
                
                plotvector = zeros(1,app.Nmeasurements(app.startTDC+1,app.stopTDC+1));
                for i = 1 : app.Nmeasurements(app.startTDC+1,app.stopTDC+1) % reconstruct vector for plotting data
                     plotvector(i) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
                end
                plotvector = plotvector / app.unitsMultiplier(app.unitsMode);
                histogram(app.UIAxes2,plotvector,app.Nbins,'Facecolor',[0.5 0 0.75],'EdgeColor','none','Normalization','pdf');
            else % fixed histogram
                
                edges = app.measurementHistogramEdges / app.unitsMultiplier(app.unitsMode);
                histogram(app.UIAxes2,'BinEdges',edges,'BinCounts',app.measurementHistogramCounts, ...
                    'Facecolor',[0.5 0 0.75],'EdgeColor','none','Normalization','pdf');
            end
            
            % counter group label
            text = sprintf( 'COUNTER\nSTART counts:\t%d\nSTOP counts:\t%d\nTime intervals:\t%d\nElapsed time:\t%.1f s', ...
                app.STARTcounts(app.startTDC+1,app.stopTDC+1), ...
                app.STOPcounts(app.startTDC+1,app.stopTDC+1), ...
                app.Nmeasurements(app.startTDC+1,app.stopTDC+1), ...
                app.totalTime(app.startTDC+1,app.stopTDC+1) );
            app.CounterLabel.Text = text;
            %fprintf([text '\n']);
        end
        
        function MeasurementPanelRefresh(app) % refresh Measurement tab display
            
            MeasurementDynamics(app);
            
            text = sprintf('Offset: %.3g %s', app.offset(app.startTDC+1,app.stopTDC+1)/app.unitsMultiplier(app.unitsMode), app.unitsSymbol(app.unitsMode));
            app.OffsetLabel.Text = text; % offset label
            
            app.windowLowField.Value = app.windowLow(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode);
            app.windowHighField.Value = app.windowHigh(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode);
            
            app.FirstSTOPCheckBox.Value = app.FirstSTOPCheckBox_var(app.startTDC+1,app.stopTDC+1);
            app.EventsEditField.Value = app.EventsEditField_var(app.startTDC+1,app.stopTDC+1);
            app.TimeEditField.Value = app.TimeEditField_var(app.startTDC+1,app.stopTDC+1);
            
        end
        
        function CalibrationDynamics(app) % dynamically refreshing part of Calibration Panel
            
            if app.counts(app.selectedTDC+1) > 0
                plotdata = app.histogramData(app.selectedTDC+1,:) / app.counts(app.selectedTDC+1);
            else
                plotdata = app.histogramData(app.selectedTDC+1,:);
            end
            bar(app.UIAxes, plotdata, 'Facecolor',[0.5 0 0.75]); % plot histogram (bar graph) for the selected TDC
            app.CountsLabel.Text = strcat( 'Counts:',{' '},int2str(app.counts(app.selectedTDC+1)) ); % display hit count for the channel
        end
        
        function CalibrationPanelRefresh(app) % refresh Calibration tab display
            
            if app.TDCenabled(app.selectedTDC+1) % determine if the channel is enabled
                
                app.EnableButton.Value = 1; % update enable button & lamp
                app.EnableButton.Text = 'Disable';
                app.EnableLamp.Color = 'g';
                
            else % channel disabled
                
                app.EnableButton.Value = 0;
                app.EnableButton.Text = 'Enable';
                if app.calibrationPts(app.selectedTDC+1) > 0
                    app.EnableLamp.Color = [0.30 0.75 0.93]; % light blue
                else
                    app.EnableLamp.Color = [0.5 0.5 0.5]; % grey
                end
            end
            
            % Status lamps for all channels
            if app.TDCenabled(1) % TDC0
                app.TDC0Lamp.Color = 'g';
            else
                if app.calibrationPts(1) > 0
                    app.TDC0Lamp.Color = [0.30 0.75 0.93];
                else
                    app.TDC0Lamp.Color = [0.5 0.5 0.5];
                end
            end
            if app.TDCenabled(2) % TDC1
                app.TDC1Lamp.Color = 'g';
            else
                if app.calibrationPts(2) > 0
                    app.TDC1Lamp.Color = [0.30 0.75 0.93];
                else
                    app.TDC1Lamp.Color = [0.5 0.5 0.5];
                end
            end
            
            CalibrationDynamics(app);
            
            if app.calibrationPts(app.selectedTDC+1) > 0 % display calibration info
                text = sprintf( 'Calibrated with %d counts.\nActive taps = %d. Avg width = %.2f ps.', ...
                app.calibrationPts(app.selectedTDC+1),app.activeTaps(app.selectedTDC+1),app.avgWidth(app.selectedTDC+1) );
                color = [0.00 0.45 0.74];
            else
                text = 'This channel has not been calibrated yet.';
                color = 'k';
            end
            app.CalibrationInfoLabel.Text = text;
            app.CalibrationInfoLabel.FontColor = color;
        end
        
        function timerCallback(app) % timer-triggered function (for e.g. SoC monitoring)
            
            %if (app.RUNButton.Value == 1) || (sum(app.TDCenabled) > 0) || (app.Benchmark2Button.Value == 1)
            if app.tcpBusy == 1
                app.timerFlag = 1; % only set the flag
            else
                readXADC(app); % execute temp measurement NOW
            end
        end
        
        function readXADC(app) % read SoC temperature
            
            if app.connected == 0
                return
            end
            app.tcpBusy = 1;
            write(app.client, uint16(bitor(bitshift(5,8),0)) ); % send command 5: read XADC
            rawTemp = read(app.client,1,'int32'); % read the 32-bit raw value
            app.tcpBusy = 0;
            temp = double(rawTemp)*503.975/4096 - 273.15; % convert to °C
            
            % display temperature
            app.tempGauge.Value = temp;
            label = sprintf('%.1f °C',temp);
            app.tempLabel.Text = label;
            if temp >= 80
                app.tempLabel.FontColor = 'r';
            else
                app.tempLabel.FontColor = 'k';
            end
        end
        
        function benchmarkTimerCallback1(app) % timer-triggered benchmark 1 stop
            
            app.tcpBusy = 1;
            write(app.client, uint16(bitor(bitshift(8,8),0))); % stop speedtest
            Nspeed = read(app.client,1,'uint64'); % number of read stamps
            app.tcpBusy = 0;
            app.Benchmark1Label.Text = sprintf( 'Read speed: %d Timestamps/Second', round(Nspeed/10)); % print result
            app.Benchmark1Button.Value = 0; % reset button
            %app.Benchmark1Button.Enable = 'on'; % re-enable button
            start(app.xadcTimer) % restart xadc timer
            activeFields(app);
        end
        
        function benchmarkTimerCallback2(app) % timer-triggered benchmark 2 stop
            
            write(app.client, uint16(bitor(bitshift(0,8),0))); % stop channel 0
            app.Benchmark2Button.Value = 0; % reset button
        end
        
        function rebin(app) % rebins measurementHistogram from TDCmeasurements for selected START-STOP pair
            
            if app.windowLow(app.startTDC+1,app.stopTDC+1) >= app.windowHigh(app.startTDC+1,app.stopTDC+1) % invalid window
                return
            end
            
            app.measurementHistogramCounts = zeros(1,app.Nbins);
            app.measurementHistogramEdges = zeros(1,app.Nbins+1);
            
            % compute edges
            app.measurementHistogramEdges(1) = app.windowLow(app.startTDC+1,app.stopTDC+1);
            delta = (app.windowHigh(app.startTDC+1,app.stopTDC+1)-app.windowLow(app.startTDC+1,app.stopTDC+1))/app.Nbins; % bin width
            for bin = 1 : app.Nbins
                app.measurementHistogramEdges(bin+1) = app.windowLow(app.startTDC+1,app.stopTDC+1) + delta*bin;
            end
            
            % fill bins
            for i = 1 : app.Nmeasurements(app.startTDC+1,app.stopTDC+1)
                
                value = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
                if (app.windowLow(app.startTDC+1,app.stopTDC+1) < value) && (value < app.windowHigh(app.startTDC+1,app.stopTDC+1)) % value has to be inside borders
                    
                    ratio = (value - app.windowLow(app.startTDC+1,app.stopTDC+1))/(app.windowHigh(app.startTDC+1,app.stopTDC+1) - app.windowLow(app.startTDC+1,app.stopTDC+1));
                    bin = floor(ratio * app.Nbins) + 1;
                    app.measurementHistogramCounts(bin) = app.measurementHistogramCounts(bin) + 1;
                end
            end
        end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % initialize some properties values
            
            app.TDCenabled = zeros(1,app.Nch);
        
            app.counts = zeros(1,app.Nch);
            app.histogramData = zeros(app.Nch,app.Ntaps);
        
            app.calibrationPts = zeros(1,app.Nch);
            app.binWidths = ones(app.Nch,app.Ntaps) * app.T/app.Ntaps;
            app.calibrationTable = zeros(app.Nch,app.Ntaps);
            
            % generate default calibration table
            for bin = 1 : app.Ntaps
                app.calibrationTable(:,bin) = (2*bin-1)/2 * app.T/app.Ntaps; % (2N-1)/2 * avg binWidth
            end
            
            app.activeTaps = ones(1,app.Nch) * app.Ntaps;
            app.avgWidth = ones(1,app.Nch) * app.T/app.Ntaps;
            
            app.DNL = zeros(app.Nch,app.Ntaps);
            app.INL = zeros(app.Nch,app.Ntaps);
            
            app.TDCmeasurements = zeros(app.Nch,app.Nch,500*app.BRAMsize);
            app.Nmeasurements = zeros(app.Nch,app.Nch);
            app.STARTcounts = zeros(app.Nch,app.Nch);
            app.STOPcounts = zeros(app.Nch,app.Nch);
            app.offset = zeros(app.Nch,app.Nch);
            app.totalTime = zeros(app.Nch,app.Nch);
            
            app.windowLow = ones(app.Nch,app.Nch) * app.windowLowField.Value; % default window (maximum range)
            app.windowHigh = ones(app.Nch,app.Nch) * app.windowHighField.Value;
            
            app.EventsEditField_var = ones(app.Nch,app.Nch) * app.EventsEditField.Value;
            app.TimeEditField_var = ones(app.Nch,app.Nch) * app.TimeEditField.Value;
            app.FirstSTOPCheckBox_var = ones(app.Nch,app.Nch) * app.FirstSTOPCheckBox.Value;
            
            app.Nbins = 200;
            rebin(app);
            
            app.StatsLabel.Visible = 'off'; % stats label invisible
            activeFields(app);
            
            % setup XADC timer
            app.xadcTimer = timer('StartDelay',0, 'Period',1, 'ExecutionMode','fixedSpacing', 'TasksToExecute',Inf); % repeated execution every 1 second
            app.xadcTimer.TimerFcn = @(~,~) timerCallback(app); % TimerFcn callback handle
            
            % setup benchmark timers
            app.benchmarkTimer1 = timer('StartDelay',10, 'ExecutionMode','singleShot'); % execute only once after 10 seconds
            app.benchmarkTimer1.TimerFcn = @(~,~) benchmarkTimerCallback1(app); % TimerFcn callback handle
            app.benchmarkTimer2 = timer('StartDelay',10, 'ExecutionMode','singleShot'); % execute only once after 10 seconds
            app.benchmarkTimer2.TimerFcn = @(~,~) benchmarkTimerCallback2(app); % TimerFcn callback handle
            
            app.fileIndex = 1;
            app.PathEditField.Value = app.fileDirectory;
            
            CalibrationPanelRefresh(app);
            MeasurementPanelRefresh(app);
            
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            value = app.AutoscaleCheckBox.Value;
            if value == 0 % switch to fixed binning
                rebin(app);
            end
            
            MeasurementPanelRefresh(app);  
        end

        % Value changed function: Benchmark1Button
        function Benchmark1ButtonValueChanged(app, event)
            value = app.Benchmark1Button.Value;
            
            if value == 1 % button pressed
                activeFields(app);
                %app.Benchmark1Button.Enable = 'off'; % disable button
                stop(app.xadcTimer) % stop xadc timer
                start(app.benchmarkTimer1) % start benchmark timer
                write(app.client, uint16(bitor(bitshift(7,8),0))); % start speedtest
            end
            
        end

        % Value changed function: Benchmark2Button
        function Benchmark2ButtonValueChanged(app, event)
            
            if app.Benchmark2Button.Value == 1 % button pressed
                activeFields(app);
                %app.Benchmark2Button.Enable = 'off'; % disable button
                stop(app.xadcTimer) % stop xadc timer
                start(app.benchmarkTimer2) % start benchmark timer
                write(app.client, uint16(bitor(bitshift(1,8),0))); % start channel 0
                
                Nspeed = 0;
                while app.Benchmark2Button.Value == 1 % test loop: download as many timestamps as possible
                    
                    app.tcpBusy = 1;
                    write(app.client, uint16(bitor(bitshift(2,8),0))); % read channel 0
                    Nstamps = read(app.client,1,'uint64'); % how many stamps are arriving
            
                    if Nstamps > 0 % stamps available
                        data = read(app.client,Nstamps,'uint64'); % collect received timestamps
                        Nspeed = Nspeed + Nstamps;
                    end
                    app.tcpBusy = 0;
                    
                    drawnow limitrate % enable loop interruption by other callbacks
                
                    if app.timerFlag % synchronous XADC read
                        readXADC(app);
                        app.timerFlag = 0;
                    end
                end
                % end of test
                app.Benchmark2Label.Text = sprintf( 'Download speed: %d Timestamps/Second', round(Nspeed/10)); % print result
                %app.Benchmark2Button.Enable = 'on'; % re-enable button
                start(app.xadcTimer) % restart xadc timer
                activeFields(app);
            end
            
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            app.Nbins = app.BinsEditField.Value;
            rebin(app);
            MeasurementPanelRefresh(app);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            
            if app.counts(app.selectedTDC+1) >= 10000
                
                % open file for writing calibration data
                filename = ['calTDC' num2str(app.selectedTDC) '.txt'];
                fileID = fopen([app.fileDirectory '\' filename],'w');
                if fileID < 0
                    uialert(app.GUI,'Error writing to calibration file.','Error');
                    return
                end
                
                app.calibrationPts(app.selectedTDC+1) = app.counts(app.selectedTDC+1); 
                fprintf(fileID,'Total counts: %d\n',app.calibrationPts(app.selectedTDC+1));
                
                for bin = app.Ntaps: -1 : 1 % decreasing from top
                    if app.histogramData(app.selectedTDC+1,bin) > 0 % the highest active tap
                        app.activeTaps(app.selectedTDC+1) = bin;
                        app.avgWidth(app.selectedTDC+1) = app.T/bin;
                        break
                    end
                end
                fprintf(fileID,'Active taps: %d\nAverage width: %.2f ps\nTemperature: %.1f °C\n\n',...
                    app.activeTaps(app.selectedTDC+1),...
                    app.avgWidth(app.selectedTDC+1),...
                    app.tempGauge.Value );
                
                fprintf(fileID,'Bin\tCounts\tCount ratio\tBin width [ps]\tTime [ps]\tDNL [LSB]\tINL [LSB]\n');
                for bin = 1 : app.Ntaps % for all bins
                
                    binWidth = app.histogramData(app.selectedTDC+1,bin)/app.calibrationPts(app.selectedTDC+1) * app.T; % get bin width in ps
                    app.binWidths(app.selectedTDC+1,bin) = binWidth;
                    
                    if bin <= app.activeTaps(app.selectedTDC+1) % DNL
                        % formula from Favi
                        binDNL = app.histogramData(app.selectedTDC+1,bin)/app.calibrationPts(app.selectedTDC+1) * app.activeTaps(app.selectedTDC+1) - 1;
                    else
                        binDNL = 0;
                    end
                    app.DNL(app.selectedTDC+1,bin) = binDNL;
                    
                    if bin == 1
                        % first element
                        app.calibrationTable(app.selectedTDC+1,bin) = binWidth/2;
                        app.INL(app.selectedTDC+1,bin) = binDNL;
                    else
                        % others : cumulative formula from Kintex7_TDC_1 (Liu)
                        app.calibrationTable(app.selectedTDC+1,bin) = app.calibrationTable(app.selectedTDC+1,bin-1) + app.binWidths(app.selectedTDC+1,bin-1)/2 + binWidth/2;
                        % INL formula from Favi
                        app.INL(app.selectedTDC+1,bin) = app.INL(app.selectedTDC+1,bin-1) + binDNL;
                    end
                    
                    % write to file
                    fprintf(fileID,'%d\t%d\t%g\t%.2f\t%.2f\t%.4f\t%.3f\n', bin, app.histogramData(app.selectedTDC+1,bin), app.histogramData(app.selectedTDC+1,bin)/app.counts(app.selectedTDC+1),...
                        binWidth, app.calibrationTable(app.selectedTDC+1,bin), binDNL, app.INL(app.selectedTDC+1,bin) );
                end
                
                fclose(fileID);
                
                CalibrationPanelRefresh(app); % refresh calibration info
                
            else
                uialert(app.GUI,'Collect at least 10000 points.','Warning','Icon','warning');
            end
            
        end

        % Selection changed function: ChannelselectionButtonGroup
        function ChannelselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelselectionButtonGroup.SelectedObject;
            
            switch selectedButton % determine selected TDC ID
                    
                case app.TDC0Button
                    app.selectedTDC = 0;
                    app.TDCLabel.Text = 'TDC 0';
                        
                case app.TDC1Button
                    app.selectedTDC = 1;
                    app.TDCLabel.Text = 'TDC 1';
            end
            
            CalibrationPanelRefresh(app); % switch view
            
        end

        % Button pushed function: ClearButton2
        function ClearButton2Pushed(app, event)
            % clear measurements and time, but not the offset
            
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,:) = 0;
            app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = 0;
            app.initialCounts = 0;
            app.STARTcounts(app.startTDC+1,app.stopTDC+1) = 0;
            app.STOPcounts(app.startTDC+1,app.stopTDC+1) = 0;
            
            app.totalTime(app.startTDC+1,app.stopTDC+1) = 0;
            app.initialTime = 0;
            app.t1 = clock;
            
            rebin(app);
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            % clear on-chip BRAM
            command = uint16(bitor(bitshift(3,8),app.selectedTDC)); % request manual BRAM clear
            write(app.client, command);
            
            % clear accumulated counts
            app.histogramData(app.selectedTDC+1,:) = 0; % overwrite the selected row with zeros
            app.counts(app.selectedTDC+1) = 0;
            
            % refresh display
            CalibrationPanelRefresh(app);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if app.connected == 0 % try to connect
                
                app.ConnectButton.Text = 'Connecting ...';
                try
                    app.client = tcpclient(app.RPaddr.Value,app.Port.Value,'Timeout',1,'ConnectTimeout',5); % wait for max 5 seconds
                    app.connected = 1; % connection established
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.BackgroundColor = 'r';
                    start(app.xadcTimer) % start monitoring timer
                    
                catch err % failed
                    switch err.identifier
                        case 'MATLAB:networklib:tcpclient:cannotCreateObject'
                            uialert(app.GUI,'Connection timeout!','Error');                      
                    end
                    app.ConnectButton.Text = 'Connect';
                    
                end
            else % disconnect
                
                delete (app.client); % 'clear' the client object/property
                app.connected = 0;
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [0.4706 0.6706 0.1882];
                stop(app.xadcTimer) % stop monitoring timer
            end
            
            activeFields(app);
        end

        % Value changed function: DropDownSTART
        function DropDownSTARTValueChanged(app, event)
            value = app.DropDownSTART.Value;
            
            switch value
                case 'TDC 0'
                    app.startTDC = 0;
                case 'TDC 1'
                    app.startTDC = 1;
            end
            
            rebin(app);
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
            activeFields(app);
        end

        % Value changed function: DropDownSTOP
        function DropDownSTOPValueChanged(app, event)
            value = app.DropDownSTOP.Value;
            
            switch value
                case 'TDC 0'
                    app.stopTDC = 0;
                case 'TDC 1'
                    app.stopTDC = 1;
            end
            
            rebin(app);
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
            activeFields(app);
        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            
            if app.EnableButton.Value == 1 % enable channel
                app.TDCenabled(app.selectedTDC+1) = 1;
                command = uint16(bitor(bitshift(1,8),app.selectedTDC));   
            else % disable channel
                app.TDCenabled(app.selectedTDC+1) = 0;
                command = uint16(bitor(bitshift(0,8),app.selectedTDC));
            end
            write(app.client, command); % send command to hardware
            %reply = read(app.client,1,'uint64'); % wait for confirmation
            CalibrationPanelRefresh(app);
            activeFields(app);
            
            if (app.EnableButton.Value == 1) && (sum(app.TDCenabled) == 1)  % enter loop ONLY IF this is the first channel to be enabled
                while sum(app.TDCenabled) > 0 % read TDC data loop
                    
                    app.tcpBusy = 1;
                    for TDCindex = 0:app.Nch-1 % scroll through all channels    
                        if app.TDCenabled(TDCindex+1) % for enabled channels
                            
                            command = uint16(bitor(bitshift(2,8),TDCindex)); % request to read the selected channel
                            write(app.client, command);
                            Nstamps = read(app.client,1,'uint64'); % how many stamps are arriving
                            
                            if Nstamps > 0 % stamps available
                                data = read(app.client,Nstamps,'uint64'); % collect received timestamps
                                for timestamp = 1 : length(data)
                                    %half = typecast(data(timestamp),'uint32'); % timestamp split in 32bit halves   
                                    %fprintf( '%s %s\n', dec2hex(half(2),8), dec2hex(half(1),8) ); % display timestamps
                                    thermocode = bitand( data(timestamp), 255); % lowest 8 bits
                                    app.histogramData(TDCindex+1, thermocode) = app.histogramData(TDCindex+1, thermocode) + 1; % increment the correspoding bin
                                end
                                app.counts(TDCindex+1) = app.counts(TDCindex+1) + Nstamps; % increase number of total counts
                            end
                        end
                    end
                    app.tcpBusy = 0;
                    
                    if app.TDCenabled(app.selectedTDC+1)
                        CalibrationDynamics(app); % refresh histogram and counts
                    end
                    drawnow limitrate % enable loop interruption by other callbacks
                    
                    if app.timerFlag % synchronous XADC read
                        readXADC(app);
                        app.timerFlag = 0;
                    end
                end
            end
        end

        % Value changed function: EventsEditField
        function EventsEditFieldValueChanged(app, event)
            app.EventsEditField_var(app.startTDC+1,app.stopTDC+1) = app.EventsEditField.Value; 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Prompt user for file name
            userinput = char(inputdlg('Enter file name:','Save As',[1 40]));
            if isempty(userinput)
                return
            end
            
            % Write histogram to txt file
            filename = [userinput '.txt'];
            
            fileID = fopen([app.fileDirectory '\' filename],'w');
            if fileID < 0
                uialert(app.GUI,'Data could not be saved.','Error');
                return
            end
            
            if app.AutoscaleCheckBox.Value == 1
                
                datavector = zeros(1,app.Nmeasurements(app.startTDC+1,app.stopTDC+1));
                for i = 1 : app.Nmeasurements(app.startTDC+1,app.stopTDC+1) % reconstruct vector of timestamps
                    datavector(i) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
                end
                [bincounts, edges] = histcounts(datavector,app.Nbins); % sort into Nbins bins
            else
                
                bincounts = app.measurementHistogramCounts;
                edges = app.measurementHistogramEdges;
            end
            % bincounts: number of hits in each bin (Nbins)
            % edges: left edges of bins + right edge of last bin (Nbins+1)
            edges = edges / app.unitsMultiplier(app.unitsMode);
            units = app.unitsSymbol(app.unitsMode);
            
            % compute PDF
            bin_width = edges(2)-edges(1);
            if app.Nmeasurements(app.startTDC+1,app.stopTDC+1) > 0
                bincounts_pdf = bincounts / (app.Nmeasurements(app.startTDC+1,app.stopTDC+1) * bin_width);
            else
                bincounts_pdf = bincounts;
            end
            
            if app.FirstSTOPCheckBox.Value == 1
                fs_option = 'On';
            else
                fs_option = 'Off';
            end
            
            fprintf(fileID,'START: TDC %d\nSTOP: TDC %d\n1st stop option: %s\nFrom: %.12g %s\nTo: %.12g %s\n',...
                app.startTDC,...
                app.stopTDC,...
                fs_option,...
                app.windowLow(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode),...
                units,...
                app.windowHigh(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode),...
                units );
            fprintf(fileID,'START counts: %d\nSTOP counts: %d\nTime intervals: %d\nElapsed time: %.1f s\nOffset: %.12g %s\n\n',...
                app.STARTcounts(app.startTDC+1,app.stopTDC+1), ...
                app.STOPcounts(app.startTDC+1,app.stopTDC+1), ...
                app.Nmeasurements(app.startTDC+1,app.stopTDC+1), ...
                app.totalTime(app.startTDC+1,app.stopTDC+1),...
                app.offset(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode), units);
                
            fprintf(fileID,'Time [%s]\tCounts\tPDF [1/%s]\n',units,units);
            for i = 1 : length(bincounts) % write Nbins rows to file
                fprintf(fileID,'%.12g\t%d\t%g\n',edges(i),bincounts(i),bincounts_pdf(i));
            end
            
            fclose(fileID);
            uialert(app.GUI,['Wrote to file ' filename],'Success','Icon','success');
            app.fileIndex = app.fileIndex + 1;
            
            % ! Run external program &
            
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            % save histogramData & counts to a .mat variable file
            
            histogramDataVar = app.histogramData;
            countsVar = app.counts;
            try
                save([app.fileDirectory '\calibrationData.mat'],'histogramDataVar','countsVar');
                uialert(app.GUI,'Saved histogram data.','Success','Icon','success');
            catch
                uialert(app.GUI,'Data could not be saved.','Error','Icon','error');
            end
        end

        % Value changed function: FirstSTOPCheckBox
        function FirstSTOPCheckBoxValueChanged(app, event)
            app.FirstSTOPCheckBox_var(app.startTDC+1,app.stopTDC+1) = app.FirstSTOPCheckBox.Value;
        end

        % Close request function: GUI
        function GUICloseRequest(app, event)
            delete(app.xadcTimer);
            delete(app.benchmarkTimer1);
            delete(app.benchmarkTimer2);
            delete(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % import histogramData & counts from a .mat variable file
            
            try
                load([app.fileDirectory '\calibrationData.mat'],'histogramDataVar','countsVar');
            catch
                uialert(app.GUI,'Data not found.','Error','Icon','error');
                return
            end
            
            app.histogramData = histogramDataVar;
            app.counts = countsVar;
            
            CalibrationPanelRefresh(app); % plot histogram and counts
            uialert(app.GUI,'Successfully imported data.','Success','Icon','success');
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            app.fileDirectory = app.PathEditField.Value; % update file path
            
        end

        % Button pushed function: PlotcalibrationdataButton
        function PlotcalibrationdataButtonPushed(app, event)
            
            % plot histogram of bin widths
            figure
            bar(app.binWidths(app.selectedTDC+1,:));
            title('Bin width')
            xlabel('Tap')
            ylabel('ps')
            
            % plot the calibration table line + reference ideal line
            figure
            plot(app.calibrationTable(app.selectedTDC+1,:),'Linewidth',1.5);
            idealLine = refline( app.avgWidth(app.selectedTDC+1), -app.avgWidth(app.selectedTDC+1)/2 );
            idealLine.Color = [0.75 0.75 0.75];
            title('Calibration table')
            xlabel('Tap')
            ylabel('Measured time [ps]')
            ylim([0 inf])
            
            % plot DNL & INL
            figure
            plot(app.DNL(app.selectedTDC+1,:),'Linewidth',1.5);
            hold on
            plot(app.INL(app.selectedTDC+1,:),'Linewidth',1.5);
            hold off
            title('Nonlinearity')
            xlabel('Tap')
            ylabel('LSB')
            grid on
            legend('DNL','INL')
            
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            
            if app.RUNButton.Value == 1 % RUN measurement
                if app.Nmeasurements(app.startTDC+1,app.stopTDC+1) < length(app.TDCmeasurements)
                    if (app.calibrationPts(app.startTDC+1) > 0) && (app.calibrationPts(app.stopTDC+1) > 0)
                        app.RUNButton.Text = 'STOP';
                        app.RUNButton.BackgroundColor = 'r';
                        
                        app.initialTime = app.totalTime(app.startTDC+1,app.stopTDC+1);
                        app.initialCounts = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
                        
                        app.t1 = clock;
                        if app.startTDC ~= app.stopTDC % START - STOP pair measurement
                            write(app.client, uint16(bitor(bitshift(3,8),app.startTDC)) ); % first clear both channels
                            write(app.client, uint16(bitor(bitshift(3,8),app.stopTDC)) );
                            write(app.client, uint16(bitor(bitshift(4,8),app.startTDC)) ); % RUN in tandem
                            write(app.client, uint16(bitor(bitshift(4,8),app.stopTDC)) );
                        
                        else % single TDC measurement
                            write(app.client, uint16(bitor(bitshift(3,8),app.startTDC)) ); % clear channel
                            write(app.client, uint16(bitor(bitshift(1,8),app.startTDC)) ); % RUN
                        end
                    else % uncalibrated channel(s)
                        uialert(app.GUI,'Missing calibration!','Warning','Icon','warning');
                        app.RUNButton.Value = 0;
                        return
                    end
                else % memory full
                    uialert(app.GUI,'Buffer full!','Info','Icon','info');
                    app.RUNButton.Value = 0;
                    return
                end
                
            else % STOP measurement
                app.RUNButton.Text = 'RUN';
                app.RUNButton.BackgroundColor = [0.30 0.75 0.93];
                
                if app.startTDC ~= app.stopTDC % pair
                    write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop and break up both channels
                else % single
                    write(app.client, uint16(bitor(bitshift(0,8),app.startTDC)) ); % stop channel
                end
            end
            
            activeFields(app);
            
            triggerBuffer = uint64(zeros(1,app.BRAMsize));
            Nstart = 0; % No. of hits in start TDC
            Nstop = 0; % No. of hits in stop TDC
            firstSTOPtriggerIndex = 0; % trigger index of last firstSTOP
            
            last = uint64(0); % last timestamp from single TDC measurement
            lastRefresh = clock; % time of last graphics refresh
            
            while app.RUNButton.Value == 1 % synced measurement loop
                
                app.tcpBusy = 1;
                if app.startTDC ~= app.stopTDC % 2-channel START-STOP time interval measurement
                    
                    % read stopTDC (events)
                    write(app.client, uint16(bitor(bitshift(2,8),app.stopTDC)) );
                    Nevents = read(app.client,1,'uint64'); % how many stop stamps are arriving
                    if Nevents > 0
                        events = read(app.client,Nevents,'uint64'); % collect received timestamps
                        Nstop = Nstop + Nevents;
                        app.STOPcounts(app.startTDC+1,app.stopTDC+1) = app.STOPcounts(app.startTDC+1,app.stopTDC+1) + Nevents;
                    end
                    
                    % read startTDC (triggers)
                    write(app.client, uint16(bitor(bitshift(2,8),app.startTDC)) );
                    Ntrigs = read(app.client,1,'uint64'); % how many trigger stamps are arriving
                    if Ntrigs > 0
                        triggers = read(app.client,Ntrigs,'uint64'); % collect received timestamps
                        for i = 1 : length(triggers) % append triggers to triggerBuffer
                            addr = bitand ( bitshift(triggers(i),-48), app.bitMask16 ); % BRAM address
                            triggerBuffer(addr+1) = triggers(i);
                        end
                        Nstart = Nstart + Ntrigs;
                        app.STARTcounts(app.startTDC+1,app.stopTDC+1) = app.STARTcounts(app.startTDC+1,app.stopTDC+1) + Ntrigs;
                    end
                    
                    % process events
                    for i = 1 : Nevents
                        trigger = bitand ( bitshift(events(i),-32), app.bitMask16 ); % trigger index for this event
                        if trigger == 0
                            continue % trigger 0 is invalid
                        end
                        if (trigger == firstSTOPtriggerIndex) && (app.FirstSTOPCheckBox.Value == 1)
                            continue % skip STOP stamps that are not first
                        end
                        if (trigger ~= firstSTOPtriggerIndex) % firstSTOP identification
                            firstSTOPtriggerIndex = trigger;
                        end
                        
                        coarseSTOP = int32(bitand ( bitshift(events(i),-8), app.bitMask24 )); % 24-bit coarseSTOP
                        thermo = bitand( events(i), app.bitMask8 ); % lowest 8 bits of stop
                        fineSTOP = app.calibrationTable(app.stopTDC+1,thermo);
                        
                        if (app.windowHigh(app.startTDC+1,app.stopTDC+1) > -app.T) % use left trigger as START
                            
                            coarseSTART = int32(bitand ( bitshift(triggerBuffer(trigger),-8), app.bitMask24 )); % 24-bit coarseSTART
                            delta = bitand ( coarseSTOP-coarseSTART, app.bitMask24 ); % 24-bit coarse difference, no overflow          
                            coarseTI = double(delta) * app.T; % coarse count difference to ps
                        
                            thermo = bitand( triggerBuffer(trigger), app.bitMask8 ); % lowest 8 bits of start
                            fineSTART = app.calibrationTable(app.startTDC+1,thermo); % convert thermocode to ps
                        
                            TI = coarseTI - (fineSTOP - fineSTART) - app.offset(app.startTDC+1,app.stopTDC+1); % measured time interval
                            if (app.windowLow(app.startTDC+1,app.stopTDC+1) < TI) && (TI < app.windowHigh(app.startTDC+1,app.stopTDC+1)) % valid time interval
                                
                                memoryIndex = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % location of the next point to be written
                                app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,memoryIndex) = TI; % save point in TDCmeasurements
                                app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % increment N
                                
                                if app.AutoscaleCheckBox.Value == 0 % manual binning
                                    ratio = (TI - app.windowLow(app.startTDC+1,app.stopTDC+1))/(app.windowHigh(app.startTDC+1,app.stopTDC+1) - app.windowLow(app.startTDC+1,app.stopTDC+1));
                                    bin = floor(ratio * app.Nbins) + 1;
                                    app.measurementHistogramCounts(bin) = app.measurementHistogramCounts(bin) + 1;
                                end
                                
                                if ( app.Nmeasurements(app.startTDC+1,app.stopTDC+1) == length(app.TDCmeasurements) ) || ... % memory full
                                   ( (app.Nmeasurements(app.startTDC+1,app.stopTDC+1) - app.initialCounts) == app.EventsEditField.Value ) % event gate
                                    
                                    app.RUNButton.Value = 0;
                                    app.RUNButton.Text = 'RUN';
                                    app.RUNButton.BackgroundColor = [0.30 0.75 0.93];
                                    write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop both channels
                                    activeFields(app);
                                    break % exit loop
                                end
                            end
                            if TI < 0 % special case when stop arrives up to one clock cycle before start
                                continue % do not proceed to the right trigger
                            end
                        end
                        
                        if (app.windowLow(app.startTDC+1,app.stopTDC+1) < 0) % use right trigger as START
                            if triggerBuffer(trigger+1) == 0
                                continue % skip if right trigger hasn't arrived yet
                            end
                            
                            coarseSTART = int32(bitand ( bitshift(triggerBuffer(trigger+1),-8), app.bitMask24 )); % 24-bit coarseSTART
                            delta = - bitand ( coarseSTART-coarseSTOP, app.bitMask24 ); % 24-bit coarse difference, no overflow, CAREFUL - negative difference!          
                            coarseTI = double(delta) * app.T; % coarse count difference to ps
                            
                            thermo = bitand( triggerBuffer(trigger+1), app.bitMask8 ); % lowest 8 bits of start
                            fineSTART = app.calibrationTable(app.startTDC+1,thermo); % convert thermocode to ps
                        
                            TI = coarseTI - (fineSTOP - fineSTART) - app.offset(app.startTDC+1,app.stopTDC+1); % measured time interval
                            if (app.windowLow(app.startTDC+1,app.stopTDC+1) < TI) && (TI < app.windowHigh(app.startTDC+1,app.stopTDC+1)) % valid time interval
                                
                                memoryIndex = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % location of the next point to be written
                                app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,memoryIndex) = TI; % save point in TDCmeasurements
                                app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % increment N
                                
                                if app.AutoscaleCheckBox.Value == 0 % manual binning
                                    ratio = (TI - app.windowLow(app.startTDC+1,app.stopTDC+1))/(app.windowHigh(app.startTDC+1,app.stopTDC+1) - app.windowLow(app.startTDC+1,app.stopTDC+1));
                                    bin = floor(ratio * app.Nbins) + 1;
                                    app.measurementHistogramCounts(bin) = app.measurementHistogramCounts(bin) + 1;
                                end
                                
                                if ( app.Nmeasurements(app.startTDC+1,app.stopTDC+1) == length(app.TDCmeasurements) ) || ... % memory full
                                   ( (app.Nmeasurements(app.startTDC+1,app.stopTDC+1) - app.initialCounts) == app.EventsEditField.Value ) % event gate
                                    
                                    app.RUNButton.Value = 0;
                                    app.RUNButton.Text = 'RUN';
                                    app.RUNButton.BackgroundColor = [0.30 0.75 0.93];
                                    write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop both channels
                                    activeFields(app);
                                    break % exit loop
                                end
                            end
                        end
                    end
                    
                    % TDCs are clearing, reset buffer
                    if (Nstart == app.BRAMsize) || (Nstop == app.BRAMsize)
                        Nstart = 0;
                        Nstop = 0;
                        triggerBuffer(1,:) = 0;
                        firstSTOPtriggerIndex = 0;
                    end   
                    
                else % single TDC measurement -> time intervals between adjacent timestamps
                    
                    % read TDC
                    write(app.client, uint16(bitor(bitshift(2,8),app.stopTDC)) );
                    Nevents = read(app.client,1,'uint64'); % how many timestamps are arriving
                    if Nevents > 0
                        events = read(app.client,Nevents,'uint64'); % collect received timestamps
                        app.STARTcounts(app.startTDC+1,app.stopTDC+1) = app.STARTcounts(app.startTDC+1,app.stopTDC+1) + Nevents;
                        app.STOPcounts(app.startTDC+1,app.stopTDC+1) = app.STOPcounts(app.startTDC+1,app.stopTDC+1) + Nevents;
                        
                        % process events
                        for i = 1 : Nevents
                            if i == 1 % the START timestamp is the last one from previous set
                                if last ~= 0
                                    coarseSTART = int32(bitand ( bitshift(last,-8), app.bitMask24 )); % 24-bit coarseSTART  
                                    thermo = bitand( last, app.bitMask8 ); % lowest 8 bits of start
                                    fineSTART = app.calibrationTable(app.stopTDC+1,thermo); % convert thermocode to ps
                                else
                                    continue % fist stamp in TDC -> invalid
                                end
                            else % the START timestamp is one index behind
                                coarseSTART = int32(bitand ( bitshift(events(i-1),-8), app.bitMask24 )); % 24-bit coarseSTART  
                                thermo = bitand( events(i-1), app.bitMask8 ); % lowest 8 bits of start
                                fineSTART = app.calibrationTable(app.stopTDC+1,thermo); % convert thermocode to ps
                            end
                            
                            coarseSTOP = int32(bitand ( bitshift(events(i),-8), app.bitMask24 )); % 24-bit coarseSTOP
                            delta = bitand ( coarseSTOP-coarseSTART, app.bitMask24 ); % 24-bit coarse difference, no overflow
                                
                            coarseTI = double(delta) * app.T; % coarse count difference to ps
                            thermo = bitand( events(i), app.bitMask8 ); % lowest 8 bits of stop
                            fineSTOP = app.calibrationTable(app.stopTDC+1,thermo); % thermocode to ps
                            TI = coarseTI - (fineSTOP - fineSTART); % measured time interval
                            
                            if (app.windowLow(app.startTDC+1,app.stopTDC+1) < TI) && (TI < app.windowHigh(app.startTDC+1,app.stopTDC+1)) % valid time interval
                                
                                memoryIndex = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % location of the next point to be written
                                app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,memoryIndex) = TI; % save point in TDCmeasurements
                                app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % increment N
                                
                                if app.AutoscaleCheckBox.Value == 0 % manual binning
                                    ratio = (TI - app.windowLow(app.startTDC+1,app.stopTDC+1))/(app.windowHigh(app.startTDC+1,app.stopTDC+1) - app.windowLow(app.startTDC+1,app.stopTDC+1));
                                    bin = floor(ratio * app.Nbins) + 1;
                                    app.measurementHistogramCounts(bin) = app.measurementHistogramCounts(bin) + 1;
                                end
                                
                                if ( app.Nmeasurements(app.startTDC+1,app.stopTDC+1) == length(app.TDCmeasurements) ) || ... % memory full
                                   ( (app.Nmeasurements(app.startTDC+1,app.stopTDC+1) - app.initialCounts) == app.EventsEditField.Value ) % event gate
                                    
                                    app.RUNButton.Value = 0;
                                    app.RUNButton.Text = 'RUN';
                                    app.RUNButton.BackgroundColor = [0.30 0.75 0.93];
                                    write(app.client, uint16(bitor(bitshift(0,8),app.startTDC)) ); % stop channel
                                    activeFields(app);
                                    break % exit loop
                                end
                            end
                        end
                        last = events(i); % last timestamp in this set
                        Nstop = Nstop + Nevents;
                    end
                    
                    % TDC is clearing, reset last timestamp
                    if Nstop == app.BRAMsize
                        Nstop = 0;
                        last = 0;
                    end
                    
                end
                app.tcpBusy = 0;
                
                % timing
                elapsed_time = etime(clock,app.t1);
                app.totalTime(app.startTDC+1,app.stopTDC+1) = app.initialTime + elapsed_time;
                if ( app.TimeEditField.Value > 0) && ( elapsed_time >= app.TimeEditField.Value ) % time gate
                    
                    app.RUNButton.Value = 0;
                    app.RUNButton.Text = 'RUN';
                    app.RUNButton.BackgroundColor = [0.30 0.75 0.93];
                    if app.startTDC ~= app.stopTDC % pair
                        write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop and break up both channels
                    else % single
                        write(app.client, uint16(bitor(bitshift(0,8),app.startTDC)) ); % stop channel
                    end
                    activeFields(app);
                end
                
                % Graphics refresh (counter label + plot)
                if ( etime(clock,lastRefresh) >= 0.1 ) || ( app.RUNButton.Value == 0 )
                    lastRefresh = clock;
                    MeasurementDynamics(app); % draw histogram & stuff
                end
                    
                drawnow limitrate % enable loop interruption by other callbacks
                
                if app.timerFlag % synchronous XADC read
                    readXADC(app);
                    app.timerFlag = 0;
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            % shift data back to original
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) + app.offset(app.startTDC+1,app.stopTDC+1);
            
            % shift window
            app.windowLow(app.startTDC+1,app.stopTDC+1) = app.windowLow(app.startTDC+1,app.stopTDC+1) + app.offset(app.startTDC+1,app.stopTDC+1);
            if app.windowLow(app.startTDC+1,app.stopTDC+1) < -48000000000
                app.windowLow(app.startTDC+1,app.stopTDC+1) = -48000000000;
            end
            app.windowHigh(app.startTDC+1,app.stopTDC+1) = app.windowHigh(app.startTDC+1,app.stopTDC+1) + app.offset(app.startTDC+1,app.stopTDC+1);
            if app.windowHigh(app.startTDC+1,app.stopTDC+1) > 48000000000
                app.windowHigh(app.startTDC+1,app.stopTDC+1) = 48000000000;
            end
            app.offset(app.startTDC+1,app.stopTDC+1) = 0; % reset offset
            
            rebin(app);
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Value changed function: ScaleSwitch
        function ScaleSwitchValueChanged(app, event)
            
            if app.ScaleSwitch.Value == 'Log' % histogram Y Scale
                set(app.UIAxes2,'YScale','log');
            else
                set(app.UIAxes2,'YScale','lin');
            end
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            if n == 0
                return
            end
            
            % get peak average
            avg = 0;
            for i = 1 : n
                avg = avg + app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            avg = avg / n;
            
            % get stdev
            sigma = 0;
            for i = 1 : n
                sigma = sigma + (app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i) - avg)^2 ;
            end
            sigma = sqrt(sigma/n);
            
            avg = avg / app.unitsMultiplier(app.unitsMode);
            sigma = sigma / app.unitsMultiplier(app.unitsMode);
            
            units = app.unitsSymbol(app.unitsMode);
            text = sprintf( 'Mean = %.6g %s\nStdev = %.3g %s', avg, units, sigma, units ); % print stats label
            app.StatsLabel.Text = text;
            app.StatsLabel.Visible = 'on';
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.TimeEditField_var(app.startTDC+1,app.stopTDC+1) = app.TimeEditField.Value;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            
            switch value
                case 'ps'
                    app.unitsMode = 1;
                    app.windowLowField.Limits = [-48000000000 48000000000];
                    app.windowHighField.Limits = [-48000000000 48000000000];
                case 'ns'
                    app.unitsMode = 2;
                    app.windowLowField.Limits = [-48000000 48000000];
                    app.windowHighField.Limits = [-48000000 48000000];
                case 'us'
                    app.unitsMode = 3;
                    app.windowLowField.Limits = [-48000 48000];
                    app.windowHighField.Limits = [-48000 48000];
                case 'ms'
                    app.unitsMode = 4;
                    app.windowLowField.Limits = [-48 48];
                    app.windowHighField.Limits = [-48 48];
            end
            
            unitLabel = sprintf('%s', app.unitsSymbol(app.unitsMode));
            app.psLabel.Text = unitLabel;
            app.psLabel_2.Text = unitLabel;
            axisLabel_x = sprintf('Time [%s]', app.unitsSymbol(app.unitsMode));
            axisLabel_y = sprintf('PDF of Counts [1/%s]', app.unitsSymbol(app.unitsMode));
            xlabel(app.UIAxes2, axisLabel_x);
            ylabel(app.UIAxes2, axisLabel_y);
            
            MeasurementPanelRefresh(app);
            StatisticsButtonPushed(app);
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            if (n == 0) || (app.startTDC == app.stopTDC)
                return
            end
            
            % get peak average
            avg = 0;
            for i = 1 : n
                avg = avg + app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            avg = avg / n;
            
            % shift data
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) - avg;
            app.offset(app.startTDC+1,app.stopTDC+1) = app.offset(app.startTDC+1,app.stopTDC+1) + avg;
            
            % shift window
            app.windowLow(app.startTDC+1,app.stopTDC+1) = app.windowLow(app.startTDC+1,app.stopTDC+1) - avg;
            if app.windowLow(app.startTDC+1,app.stopTDC+1) < -48000000000
                app.windowLow(app.startTDC+1,app.stopTDC+1) = -48000000000;
            end
            app.windowHigh(app.startTDC+1,app.stopTDC+1) = app.windowHigh(app.startTDC+1,app.stopTDC+1) - avg;
            if app.windowHigh(app.startTDC+1,app.stopTDC+1) > 48000000000
                app.windowHigh(app.startTDC+1,app.stopTDC+1) = 48000000000;
            end
            
            rebin(app);
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Value changed function: windowHighField
        function windowHighFieldValueChanged(app, event)
            
            if app.windowHighField.Value <= app.windowLow(app.startTDC+1,app.stopTDC+1)/app.unitsMultiplier(app.unitsMode) % invalid window
                uialert(app.GUI,'Window makes no sense.','Error','Icon','error');
                app.windowHighField.Value = app.windowHigh(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode); % back to previous value
            else 
                app.windowHigh(app.startTDC+1,app.stopTDC+1) = app.windowHighField.Value * app.unitsMultiplier(app.unitsMode);
                rebin(app);
                MeasurementDynamics(app);
            end
        end

        % Value changed function: windowLowField
        function windowLowFieldValueChanged(app, event)
            
            if app.windowLowField.Value >= app.windowHigh(app.startTDC+1,app.stopTDC+1)/app.unitsMultiplier(app.unitsMode) % invalid window
                uialert(app.GUI,'Window makes no sense.','Error','Icon','error');
                app.windowLowField.Value = app.windowLow(app.startTDC+1,app.stopTDC+1) / app.unitsMultiplier(app.unitsMode); % back to previous value
            else 
                app.windowLow(app.startTDC+1,app.stopTDC+1) = app.windowLowField.Value * app.unitsMultiplier(app.unitsMode);
                rebin(app);
                MeasurementDynamics(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'Red Pitaya TDC - by Michel Adamič';
            app.GUI.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.RedPitayaIPAddressLabel = uilabel(app.GUI);
            app.RedPitayaIPAddressLabel.Text = 'Red Pitaya IP Address:';
            app.RPaddr = uieditfield(app.GUI, 'text');
            app.RPaddr.Value = '169.254.66.178';
            app.PortEditFieldLabel = uilabel(app.GUI);
            app.PortEditFieldLabel.Text = 'Port:';
            app.Port = uieditfield(app.GUI, 'numeric');
            app.Port.Value = 1001;
            app.ConnectButton = uibutton(app.GUI, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.TabGroup = uitabgroup(app.GUI);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.UIAxes = uiaxes(app.CalibrationTab);
            title(app.UIAxes, 'Statistical code density test')
            xlabel(app.UIAxes, 'Delay line taps')
            ylabel(app.UIAxes, 'Count ratio')
            app.UIAxes.XLim = [0 192];
            app.ChannelselectionButtonGroup = uibuttongroup(app.CalibrationTab);
            app.ChannelselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelselectionButtonGroupSelectionChanged, true);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.TDC0Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.TDC0Button.Text = 'TDC 0';
            app.TDC0Button.Value = true;
            app.TDC1Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.TDC1Button.Text = 'TDC 1';
            app.TDCLabel = uilabel(app.CalibrationTab);
            app.TDCLabel.Text = 'TDC 0';
            app.CalibrationInfoLabel = uilabel(app.CalibrationTab);
            app.CalibrationInfoLabel.Text = 'This channel has not been calibrated yet.';
            app.EnableButton = uibutton(app.CalibrationTab, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.EnableLamp = uilamp(app.CalibrationTab);
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ClearButton = uibutton(app.CalibrationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CountsLabel = uilabel(app.CalibrationTab);
            app.CountsLabel.Text = 'Counts: 0';
            app.PlotcalibrationdataButton = uibutton(app.CalibrationTab, 'push');
            app.PlotcalibrationdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcalibrationdataButtonPushed, true);
            app.PlotcalibrationdataButton.Text = 'Plot calibration data';
            app.ImportButton = uibutton(app.CalibrationTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.HistogramdataLabel = uilabel(app.CalibrationTab);
            app.HistogramdataLabel.Text = 'Histogram data';
            app.TDC0LampLabel = uilabel(app.CalibrationTab);
            app.TDC0LampLabel.Text = 'TDC0';
            app.TDC0Lamp = uilamp(app.CalibrationTab);
            app.ChannelstatusLabel = uilabel(app.CalibrationTab);
            app.ChannelstatusLabel.Text = 'Channel status';
            app.TDC1LampLabel = uilabel(app.CalibrationTab);
            app.TDC1LampLabel.Text = 'TDC1';
            app.TDC1Lamp = uilamp(app.CalibrationTab);
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes2 = uiaxes(app.MeasurementTab);
            title(app.UIAxes2, 'Time interval distribution')
            xlabel(app.UIAxes2, 'Time [ps]')
            ylabel(app.UIAxes2, 'PDF of Counts [1/ps]')
            app.DropDownSTART = uidropdown(app.MeasurementTab);
            app.DropDownSTART.Items = {'TDC 0', 'TDC 1'};
            app.DropDownSTART.ValueChangedFcn = createCallbackFcn(app, @DropDownSTARTValueChanged, true);
            app.DropDownSTART.Value = 'TDC 0';
            app.STARTLabel = uilabel(app.MeasurementTab);
            app.STARTLabel.Text = 'START';
            app.STOPLabel = uilabel(app.MeasurementTab);
            app.STOPLabel.Text = 'STOP';
            app.DropDownSTOP = uidropdown(app.MeasurementTab);
            app.DropDownSTOP.Items = {'TDC 0', 'TDC 1'};
            app.DropDownSTOP.ValueChangedFcn = createCallbackFcn(app, @DropDownSTOPValueChanged, true);
            app.DropDownSTOP.Value = 'TDC 0';
            app.RUNButton = uibutton(app.MeasurementTab, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Text = 'RUN';
            app.CounterLabel = uilabel(app.MeasurementTab);
            app.CounterLabel.Text = 'COUNTER';
            app.OffsetLabel = uilabel(app.MeasurementTab);
            app.OffsetLabel.Text = 'Offset: 0 ps';
            app.ZeroButton = uibutton(app.MeasurementTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.ResetButton = uibutton(app.MeasurementTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatisticsButton = uibutton(app.MeasurementTab, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.ClearButton2 = uibutton(app.MeasurementTab, 'push');
            app.ClearButton2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton2Pushed, true);
            app.ClearButton2.Text = 'Clear';
            app.StatsLabel = uilabel(app.MeasurementTab);
            app.StatsLabel.Text = 'Stats';
            app.MeasurementwindowLabel = uilabel(app.MeasurementTab);
            app.MeasurementwindowLabel.Text = 'Measurement window';
            app.windowLowField = uieditfield(app.MeasurementTab, 'numeric');
            app.windowLowField.Limits = [-48000000000 48000000000];
            app.windowLowField.ValueDisplayFormat = '%.5g';
            app.windowLowField.ValueChangedFcn = createCallbackFcn(app, @windowLowFieldValueChanged, true);
            app.windowLowField.Value = -48000000000;
            app.ScaleSwitch = uiswitch(app.MeasurementTab, 'slider');
            app.ScaleSwitch.Items = {'Lin', 'Log'};
            app.ScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ScaleSwitchValueChanged, true);
            app.ScaleSwitch.Value = 'Lin';
            app.ExportButton = uibutton(app.MeasurementTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FromLabel = uilabel(app.MeasurementTab);
            app.FromLabel.Text = 'From';
            app.psLabel = uilabel(app.MeasurementTab);
            app.psLabel.Text = 'ps';
            app.ToEditFieldLabel = uilabel(app.MeasurementTab);
            app.ToEditFieldLabel.Text = 'To';
            app.windowHighField = uieditfield(app.MeasurementTab, 'numeric');
            app.windowHighField.Limits = [-48000000000 48000000000];
            app.windowHighField.ValueDisplayFormat = '%.5g';
            app.windowHighField.ValueChangedFcn = createCallbackFcn(app, @windowHighFieldValueChanged, true);
            app.windowHighField.Value = 48000000000;
            app.psLabel_2 = uilabel(app.MeasurementTab);
            app.psLabel_2.Text = 'ps';
            app.FirstSTOPCheckBox = uicheckbox(app.MeasurementTab);
            app.FirstSTOPCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstSTOPCheckBoxValueChanged, true);
            app.FirstSTOPCheckBox.Text = '1st STOP';
            app.sLabel = uilabel(app.MeasurementTab);
            app.sLabel.Text = 's';
            app.GATELabel = uilabel(app.MeasurementTab);
            app.GATELabel.Text = 'GATE';
            app.AutoscaleCheckBox = uicheckbox(app.MeasurementTab);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.BinsEditFieldLabel = uilabel(app.MeasurementTab);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.MeasurementTab, 'numeric');
            app.BinsEditField.Limits = [1 1000];
            app.BinsEditField.RoundFractionalValues = 'on';
            app.BinsEditField.ValueDisplayFormat = '%.0f';
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Value = 200;
            app.EventsEditFieldLabel = uilabel(app.MeasurementTab);
            app.EventsEditFieldLabel.Text = 'Events';
            app.EventsEditField = uieditfield(app.MeasurementTab, 'numeric');
            app.EventsEditField.Limits = [1 1024000];
            app.EventsEditField.ValueDisplayFormat = '%.0f';
            app.EventsEditField.ValueChangedFcn = createCallbackFcn(app, @EventsEditFieldValueChanged, true);
            app.EventsEditField.Value = 1024000;
            app.TimeEditFieldLabel = uilabel(app.MeasurementTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.MeasurementTab, 'numeric');
            app.TimeEditField.Limits = [0 Inf];
            app.TimeEditField.ValueDisplayFormat = '%11.6g';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.UnitsDropDownLabel = uilabel(app.MeasurementTab);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.MeasurementTab);
            app.UnitsDropDown.Items = {'ps', 'ns', 'us', 'ms'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'ps';
            app.SpeedtestTab = uitab(app.TabGroup);
            app.SpeedtestTab.Title = 'Speedtest';
            app.TimestampcollectionbenchmarkLabel = uilabel(app.SpeedtestTab);
            app.TimestampcollectionbenchmarkLabel.Text = 'Timestamp collection benchmark';
            app.ULabel = uilabel(app.SpeedtestTab);
            app.ULabel.Text = 'Uses channel 0 (TDC0) to measure the maximum number of collected timestamps in a 10 second interval.';
            app.ARMCprogramLabel = uilabel(app.SpeedtestTab);
            app.ARMCprogramLabel.Text = 'ARM C program:';
            app.Benchmark1Button = uibutton(app.SpeedtestTab, 'state');
            app.Benchmark1Button.ValueChangedFcn = createCallbackFcn(app, @Benchmark1ButtonValueChanged, true);
            app.Benchmark1Button.Text = 'Run';
            app.Benchmark1Label = uilabel(app.SpeedtestTab);
            app.Benchmark1Label.Text = 'Read speed: 0 Timestamps/Second';
            app.MatlabGUILabel = uilabel(app.SpeedtestTab);
            app.MatlabGUILabel.Text = 'Matlab GUI:';
            app.Benchmark2Button = uibutton(app.SpeedtestTab, 'state');
            app.Benchmark2Button.ValueChangedFcn = createCallbackFcn(app, @Benchmark2ButtonValueChanged, true);
            app.Benchmark2Button.Text = 'Run';
            app.Benchmark2Label = uilabel(app.SpeedtestTab);
            app.Benchmark2Label.Text = 'Download speed: 0 Timestamps/Second';
            app.SystemTempLabel = uilabel(app.GUI);
            app.SystemTempLabel.Text = 'System Temp';
            app.tempGauge = uigauge(app.GUI, 'ninetydegree');
            app.tempGauge.Limits = [0 90];
            app.tempGauge.MajorTicks = [0 30 60 90];
            app.tempLabel = uilabel(app.GUI);
            app.tempLabel.Text = 'N/A';
            app.PathLabel = uilabel(app.GUI);
            app.PathLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.GUI, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDCgui5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end